classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        CurrentprojectLabel       matlab.ui.control.Label
        SaveProjectButton_2       matlab.ui.control.Button
        LoadProjectButton_2       matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        RAWTab                    matlab.ui.container.Tab
        ArrayNamePreview          matlab.ui.control.Label
        ZeroexpandButton          matlab.ui.control.Button
        ZsizeEditField            matlab.ui.control.NumericEditField
        ZsizeEditFieldLabel       matlab.ui.control.Label
        YsizeEditField            matlab.ui.control.NumericEditField
        YsizeEditFieldLabel       matlab.ui.control.Label
        XsizeEditField            matlab.ui.control.NumericEditField
        XsizeEditFieldLabel       matlab.ui.control.Label
        outputnameEditField       matlab.ui.control.EditField
        outputnameEditFieldLabel  matlab.ui.control.Label
        FFTButton                 matlab.ui.control.Button
        OpenButton                matlab.ui.control.Button
        FilesRawListBox           matlab.ui.control.ListBox
        FilesListBoxLabel         matlab.ui.control.Label
        ARRAYSTab                 matlab.ui.container.Tab
        newnameEditField          matlab.ui.control.EditField
        newnameEditFieldLabel     matlab.ui.control.Label
        SaveButton_2              matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        x2Button                  matlab.ui.control.Button
        SubsamplingListBox        matlab.ui.control.ListBox
        SubsamplingListBoxLabel   matlab.ui.control.Label
        OpenButton_2              matlab.ui.control.Button
        FilesListBox_Array        matlab.ui.control.ListBox
        FilesListBox_2Label       matlab.ui.control.Label
        MASKSTab                  matlab.ui.container.Tab
        LayerEditField            matlab.ui.control.NumericEditField
        LayerEditFieldLabel       matlab.ui.control.Label
        ArrayDropDown             matlab.ui.control.DropDown
        ArrayDropDownLabel        matlab.ui.control.Label
        CreateButton              matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        OpenButton_3              matlab.ui.control.Button
        FilesListBox_3            matlab.ui.control.ListBox
        FilesListBox_3Label       matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        RightPanel                matlab.ui.container.Panel
        MaskLabel                 matlab.ui.control.Label
        ArrayLabel_2              matlab.ui.control.Label
        MaskLabel_2               matlab.ui.control.Label
        ArraysLabel               matlab.ui.control.Label
        ArrayLabel                matlab.ui.control.Label
        View3DButton              matlab.ui.control.Button
        Tree2                     matlab.ui.container.Tree
        QualityNode               matlab.ui.container.TreeNode
        CNRNode                   matlab.ui.container.TreeNode
        SNRNode                   matlab.ui.container.TreeNode
        CommonNode                matlab.ui.container.TreeNode
        MEANNode                  matlab.ui.container.TreeNode
        SUBNode                   matlab.ui.container.TreeNode
        CalculateButton           matlab.ui.control.Button
        CompareButton             matlab.ui.control.Button
        ShowButton                matlab.ui.control.Button
        AnalyseLabel              matlab.ui.control.Label
        ContextMenu               matlab.ui.container.ContextMenu
        DeleteMenu_2              matlab.ui.container.Menu
        RenameMenu                matlab.ui.container.Menu
        ContextMenu2              matlab.ui.container.ContextMenu
        SelectasMenu              matlab.ui.container.Menu
        NoiseMenu                 matlab.ui.container.Menu
        SignalMenu                matlab.ui.container.Menu
        DeleteMenu                matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        somePath % Путь к файлу или папке
        serFilePath
    end
    
    properties (Access = public)
        RawFiles = {} % Description
        FFTArrays = {} % Новое свойство для хранения структур FFT-массивов
    end
    
    methods (Access = public)

        function updateLists(app)
            %% Обновляем список RawFiles
            rawList = {};
            rawListData = {};
            for i = 1:length(app.RawFiles)
                fileInfo = app.RawFiles{i};
                % Формируем «красивое» имя для отображения
                displayName = sprintf('%s [%dx%dx%d]', fileInfo.listName, ...
                    fileInfo.dimensions(1), fileInfo.dimensions(2), fileInfo.dimensions(3));

                rawList{end+1} = displayName;
                % А здесь сохраняем реальное имя переменной,
                % которое лежит в базовом workspace
                rawListData{end+1} = i;
            end

            % Присваиваем списку
            app.FilesRawListBox.Items = rawList;
            app.FilesRawListBox.ItemsData = rawListData;

            %% Аналогично для FFTArrays
            fftList = {};
            fftListData = {};
            for i = 1:length(app.FFTArrays)
                fileInfo = app.FFTArrays{i};
                dims = fileInfo.dimensions;
                if numel(dims) < 3
                    dims(end+1:3) = 1;
                end
                displayName = sprintf('%s [%dx%dx%d]', ...
                    fileInfo.listName, dims(1), dims(2), dims(3));

                fftList{end+1} = displayName;
                fftListData{end+1} = fileInfo.listName;
            end

            app.FilesListBox_Array.Items = fftList;
            app.FilesListBox_Array.ItemsData = fftListData;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FormStartUp(app)
            rawFileStruct = load('x56.mat');
            app.RawFiles{end+1} = rawFileStruct.RawFiles{1};
            app.updateLists();
        end

        % Button pushed function: OpenButton
        function OpenRaw(app, event)
            % Открываем диалог выбора файла для файла ser
            [file, path] = uigetfile({'*.*','Все файлы'}, 'Выберите файл ser');
    
            % Если пользователь не выбрал файл, прекращаем выполнение
            if isequal(file,0)
               uialert(app.UIFigure, 'Файл не выбран.', 'Предупреждение');
               return;
            end
    
            % Формируем полный путь к файлу
            serFilePath = fullfile(path, file);

            % Вызываем функцию для чтения файла ser и сопутствующих файлов с размерностями
            openRawFileCallback(app,serFilePath); 
        end

        % Callback function: not associated with a component
        function AvaliableRawFilesListBox(app, event)
            
            
        end

        % Button pushed function: FFTButton
        function FFT(app, event)
            FFTButtonPushed(app)
        end

        % Value changed function: FilesRawListBox, outputnameEditField
        function RawListItemChanged(app, event)
            indexRawFile = app.FilesRawListBox.Value;
            if isempty(indexRawFile)
                app.ArrayNamePreview.Text = "raw file is not chosen!";
            else
                app.ArrayNamePreview.Text = strcat(app.outputnameEditField.Value, app.RawFiles{indexRawFile}.listName);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {539, 539};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {444, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.RAWTab = uitab(app.TabGroup);
            app.RAWTab.Title = 'RAW';
            app.FilesListBoxLabel = uilabel(app.RAWTab);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesRawListBox = uilistbox(app.RAWTab);
            app.FilesRawListBox.Items = {};
            app.FilesRawListBox.ValueChangedFcn = createCallbackFcn(app, @RawListItemChanged, true);
            app.FilesRawListBox.Value = {};
            app.OpenButton = uibutton(app.RAWTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRaw, true);
            app.OpenButton.Text = 'Open';
            app.FFTButton = uibutton(app.RAWTab, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFT, true);
            app.FFTButton.Text = 'FFT';
            app.outputnameEditFieldLabel = uilabel(app.RAWTab);
            app.outputnameEditFieldLabel.Text = 'output name';
            app.outputnameEditField = uieditfield(app.RAWTab, 'text');
            app.outputnameEditField.ValueChangedFcn = createCallbackFcn(app, @RawListItemChanged, true);
            app.outputnameEditField.Value = 'Array';
            app.XsizeEditFieldLabel = uilabel(app.RAWTab);
            app.XsizeEditFieldLabel.Enable = 'off';
            app.XsizeEditFieldLabel.Text = 'X size';
            app.XsizeEditField = uieditfield(app.RAWTab, 'numeric');
            app.XsizeEditField.Enable = 'off';
            app.YsizeEditFieldLabel = uilabel(app.RAWTab);
            app.YsizeEditFieldLabel.Enable = 'off';
            app.YsizeEditFieldLabel.Text = 'Y size';
            app.YsizeEditField = uieditfield(app.RAWTab, 'numeric');
            app.YsizeEditField.Enable = 'off';
            app.ZsizeEditFieldLabel = uilabel(app.RAWTab);
            app.ZsizeEditFieldLabel.Enable = 'off';
            app.ZsizeEditFieldLabel.Text = 'Z size';
            app.ZsizeEditField = uieditfield(app.RAWTab, 'numeric');
            app.ZsizeEditField.Enable = 'off';
            app.ZeroexpandButton = uibutton(app.RAWTab, 'push');
            app.ZeroexpandButton.Enable = 'off';
            app.ZeroexpandButton.Text = 'Zero expand';
            app.ArrayNamePreview = uilabel(app.RAWTab);
            app.ArrayNamePreview.Text = 'output name label';
            app.ARRAYSTab = uitab(app.TabGroup);
            app.ARRAYSTab.Title = 'ARRAYS';
            app.FilesListBox_2Label = uilabel(app.ARRAYSTab);
            app.FilesListBox_2Label.Text = 'Files';
            app.FilesListBox_Array = uilistbox(app.ARRAYSTab);
            app.FilesListBox_Array.Items = {};
            app.FilesListBox_Array.Value = {};
            app.OpenButton_2 = uibutton(app.ARRAYSTab, 'push');
            app.OpenButton_2.Text = 'Open';
            app.SubsamplingListBoxLabel = uilabel(app.ARRAYSTab);
            app.SubsamplingListBoxLabel.Text = 'Subsampling';
            app.SubsamplingListBox = uilistbox(app.ARRAYSTab);
            app.SubsamplingListBox.Items = {'X =', 'Y =', 'Z ='};
            app.SubsamplingListBox.Value = 'X =';
            app.x2Button = uibutton(app.ARRAYSTab, 'push');
            app.x2Button.Text = 'x2';
            app.Button_2 = uibutton(app.ARRAYSTab, 'push');
            app.Button_2.Text = '/2';
            app.SaveButton_2 = uibutton(app.ARRAYSTab, 'push');
            app.SaveButton_2.Text = 'Save';
            app.newnameEditFieldLabel = uilabel(app.ARRAYSTab);
            app.newnameEditFieldLabel.Text = 'new name';
            app.newnameEditField = uieditfield(app.ARRAYSTab, 'text');
            app.MASKSTab = uitab(app.TabGroup);
            app.MASKSTab.Title = 'MASKS';
            app.FilesListBox_3Label = uilabel(app.MASKSTab);
            app.FilesListBox_3Label.Text = 'Files';
            app.FilesListBox_3 = uilistbox(app.MASKSTab);
            app.OpenButton_3 = uibutton(app.MASKSTab, 'push');
            app.OpenButton_3.Text = 'Open';
            app.SaveButton = uibutton(app.MASKSTab, 'push');
            app.SaveButton.Text = 'Save';
            app.CreateButton = uibutton(app.MASKSTab, 'push');
            app.CreateButton.Text = 'Create';
            app.ArrayDropDownLabel = uilabel(app.MASKSTab);
            app.ArrayDropDownLabel.Text = 'Array';
            app.ArrayDropDown = uidropdown(app.MASKSTab);
            app.LayerEditFieldLabel = uilabel(app.MASKSTab);
            app.LayerEditFieldLabel.Text = 'Layer';
            app.LayerEditField = uieditfield(app.MASKSTab, 'numeric');
            app.LoadProjectButton_2 = uibutton(app.LeftPanel, 'push');
            app.LoadProjectButton_2.Text = 'Load Project';
            app.SaveProjectButton_2 = uibutton(app.LeftPanel, 'push');
            app.SaveProjectButton_2.Text = 'Save Project';
            app.CurrentprojectLabel = uilabel(app.LeftPanel);
            app.CurrentprojectLabel.Text = 'Current project';
            app.RightPanel = uipanel(app.GridLayout);
            app.AnalyseLabel = uilabel(app.RightPanel);
            app.AnalyseLabel.Text = 'Analyse';
            app.ShowButton = uibutton(app.RightPanel, 'push');
            app.ShowButton.Text = 'Show';
            app.CompareButton = uibutton(app.RightPanel, 'push');
            app.CompareButton.Text = 'Compare';
            app.CalculateButton = uibutton(app.RightPanel, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.Tree2 = uitree(app.RightPanel);
            app.QualityNode = uitreenode(app.Tree2);
            app.QualityNode.Text = 'Quality ';
            app.CNRNode = uitreenode(app.QualityNode);
            app.CNRNode.Text = 'CNR';
            app.SNRNode = uitreenode(app.QualityNode);
            app.SNRNode.Text = 'SNR';
            app.CommonNode = uitreenode(app.Tree2);
            app.CommonNode.Text = 'Common';
            app.MEANNode = uitreenode(app.CommonNode);
            app.MEANNode.Text = 'MEAN';
            app.SUBNode = uitreenode(app.CommonNode);
            app.SUBNode.Text = 'SUB';
            app.View3DButton = uibutton(app.RightPanel, 'push');
            app.View3DButton.Text = 'View 3D';
            app.ArrayLabel = uilabel(app.RightPanel);
            app.ArrayLabel.Text = 'Array:';
            app.ArraysLabel = uilabel(app.RightPanel);
            app.ArraysLabel.Text = 'Arrays:';
            app.MaskLabel_2 = uilabel(app.RightPanel);
            app.MaskLabel_2.Text = 'Mask:';
            app.ArrayLabel_2 = uilabel(app.RightPanel);
            app.ArrayLabel_2.Text = 'Array:';
            app.MaskLabel = uilabel(app.RightPanel);
            app.MaskLabel.Text = 'Mask:';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.DeleteMenu_2 = uimenu(app.ContextMenu);
            app.DeleteMenu_2.Text = 'Delete';
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.Text = 'Rename';
            % Assign app.ContextMenu
            app.FilesRawListBox.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.SelectasMenu = uimenu(app.ContextMenu2);
            app.SelectasMenu.Text = 'Select as';
            app.NoiseMenu = uimenu(app.SelectasMenu);
            app.NoiseMenu.Text = 'Noise';
            app.SignalMenu = uimenu(app.SelectasMenu);
            app.SignalMenu.Text = 'Signal';
            app.DeleteMenu = uimenu(app.ContextMenu2);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.ContextMenu2
            app.FilesListBox_Array.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @FormStartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end